classdef PathPlanner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GeneratetheflightpathUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        OptionsPanel                   matlab.ui.container.Panel
        ObstaclesButtonGroup           matlab.ui.container.ButtonGroup
        MultipleobstaclesButton        matlab.ui.control.RadioButton
        OneobstacleButton              matlab.ui.control.RadioButton
        NoobstaclesButton              matlab.ui.control.RadioButton
        DisplayPanel                   matlab.ui.container.Panel
        CloseButton                    matlab.ui.control.Button
        GeneratepathButton             matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        NumberofWaypoints10Label       matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        MapImage % Description
    end
    
    properties (Access = private)
        AxesProps % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ObstaclesButtonGroupSelectionChanged;
            
            app.AxesProps = struct;
            app.AxesProps.XLim = app.UIAxes.XLim;
            app.AxesProps.YLim = app.UIAxes.YLim;
            app.AxesProps.Position = app.UIAxes.Position;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.GeneratetheflightpathUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {313, 313};
                app.GridLayout.ColumnWidth = {'1x'};
                app.DisplayPanel.Layout.Row = 2;
                app.DisplayPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.DisplayPanel.Layout.Row = 1;
                app.DisplayPanel.Layout.Column = 2;
            end
        end

        % Selection changed function: ObstaclesButtonGroup
        function ObstaclesButtonGroupSelectionChanged(app, event)
            selectedButton = app.ObstaclesButtonGroup.SelectedObject;
            mapping = containers.Map( ...
                {app.NoobstaclesButton.Text, ...
                 app.OneobstacleButton.Text, ...
                 app.MultipleobstaclesButton.Text}, ...
                {'imageMap1.png', ...
                 'imageMap2.png', ...
                 'imageMap3.png'});
             
             imagePath = ['maps/', mapping(selectedButton.Text)];
             
             app.MapImage = imread(imagePath);
             
             imshow(app.MapImage, 'Parent', app.UIAxes);
             title(app.UIAxes, 'Generate a path')
             
             if ~isempty(app.AxesProps)
                 app.UIAxes.XLim = app.AxesProps.XLim;
                 app.UIAxes.YLim = app.AxesProps.YLim;
                 app.UIAxes.Position = app.AxesProps.Position;
             end
        end

        % Button pushed function: GeneratepathButton
        function GeneratepathButtonPushed(app, event)
             %nWaypoints = app.NumberofWaypointsSlider.Value;
            %[waypoints, timespot, way] = appPathPlanning(app, nWaypoints);
            [waypoints, timespot, way, T_stop, scenario] = appPathPlanning(app);
            
            assignin("base", "waypoints", waypoints);
            assignin("base", "timespot", timespot);
            assignin("base", "way", way);
            assignin("base", "T_stop", T_stop);
            assignin("base", "scenario", scenario);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.GeneratetheflightpathUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GeneratetheflightpathUIFigure = uifigure('Visible', 'off');
            app.GeneratetheflightpathUIFigure.Name = 'Generate the flight path';
            app.GeneratetheflightpathUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.GeneratetheflightpathUIFigure);
            app.OptionsPanel = uipanel(app.GridLayout);
            app.OptionsPanel.Title = 'Map Options';
            app.ObstaclesButtonGroup = uibuttongroup(app.OptionsPanel);
            app.ObstaclesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ObstaclesButtonGroupSelectionChanged, true);
            app.ObstaclesButtonGroup.Title = 'Obstacles';
            app.NoobstaclesButton = uiradiobutton(app.ObstaclesButtonGroup);
            app.NoobstaclesButton.Text = 'No obstacles';
            app.OneobstacleButton = uiradiobutton(app.ObstaclesButtonGroup);
            app.OneobstacleButton.Text = 'One obstacle';
            app.OneobstacleButton.Value = true;
            app.MultipleobstaclesButton = uiradiobutton(app.ObstaclesButtonGroup);
            app.MultipleobstaclesButton.Text = 'Multiple obstacles';
            app.DisplayPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.DisplayPanel);
            title(app.UIAxes, 'Map')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.GeneratepathButton = uibutton(app.DisplayPanel, 'push');
            app.GeneratepathButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratepathButtonPushed, true);
            app.GeneratepathButton.Text = 'Generate path';
            app.CloseButton = uibutton(app.DisplayPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.NumberofWaypoints10Label = uilabel(app.GeneratetheflightpathUIFigure);
            app.NumberofWaypoints10Label.Text = 'Number of Waypoints: 10';
            % Show the figure after all components are created
            app.GeneratetheflightpathUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PathPlanner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GeneratetheflightpathUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GeneratetheflightpathUIFigure)
        end
    end
end